classdef FTIR_data_analysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        CurrentdataLabel               matlab.ui.control.Label
        CurrentdataTextArea            matlab.ui.control.TextArea
        DiffusionCoefficientTextAreaLabel  matlab.ui.control.Label
        DiffusionCoefficientTextArea   matlab.ui.control.TextArea
        OtherparametersTextAreaLabel   matlab.ui.control.Label
        OtherparametersTextArea        matlab.ui.control.TextArea
        TabGroup                       matlab.ui.container.TabGroup
        GasLineFitTab                  matlab.ui.container.Tab
        SpectrumEditFieldLabel         matlab.ui.control.Label
        SpectrumEditField              matlab.ui.control.NumericEditField
        centerEditFieldLabel           matlab.ui.control.Label
        centerEditField                matlab.ui.control.NumericEditField
        wgEditFieldLabel               matlab.ui.control.Label
        wgEditField                    matlab.ui.control.NumericEditField
        wlEditFieldLabel               matlab.ui.control.Label
        wlEditField                    matlab.ui.control.NumericEditField
        a1EditFieldLabel               matlab.ui.control.Label
        a1EditField                    matlab.ui.control.NumericEditField
        a2EditFieldLabel               matlab.ui.control.Label
        a2EditField                    matlab.ui.control.NumericEditField
        a3EditFieldLabel               matlab.ui.control.Label
        a3EditField                    matlab.ui.control.NumericEditField
        c0EditFieldLabel               matlab.ui.control.Label
        c0EditField                    matlab.ui.control.NumericEditField
        c1EditFieldLabel               matlab.ui.control.Label
        c1EditField                    matlab.ui.control.NumericEditField
        InitalFitGuessLabel            matlab.ui.control.Label
        TestParametersButton           matlab.ui.control.Button
        DoFitButton                    matlab.ui.control.Button
        LoadNewDataButton              matlab.ui.control.Button
        ClearDataButton                matlab.ui.control.Button
        TextArea                       matlab.ui.control.TextArea
        UIAxes3                        matlab.ui.control.UIAxes
        ExamineSpectraTab              matlab.ui.container.Tab
        PlotSpectraButton              matlab.ui.control.Button
        SampleVolumeLEditField_2Label  matlab.ui.control.Label
        SampleVolumeLEditField         matlab.ui.control.NumericEditField
        SpacerSizemEditField_2Label    matlab.ui.control.Label
        SpacerSizemEditField           matlab.ui.control.NumericEditField
        TimeIntervalsEditField_2Label  matlab.ui.control.Label
        TimeIntervalsEditField         matlab.ui.control.NumericEditField
        ExperimentalParametersLabel    matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        CurveFittingTab                matlab.ui.container.Tab
        FinalConcTextArea              matlab.ui.control.TextArea
        TryFitButton                   matlab.ui.control.Button
        SaturatedConcButtonGroup       matlab.ui.container.ButtonGroup
        SelectOneButton                matlab.ui.control.ToggleButton
        UseLastSpectrumButton          matlab.ui.control.ToggleButton
        GetFinalSpectrumButton         matlab.ui.control.ToggleButton
        SeeFullFitCurveCheckBox        matlab.ui.control.CheckBox
        NmaxSpinner_2Label             matlab.ui.control.Label
        NmaxSpinner                    matlab.ui.control.Spinner
        LowerEditFieldLabel_2          matlab.ui.control.Label
        LowerEditFieldD                matlab.ui.control.NumericEditField
        UpperEditFieldLabel_2          matlab.ui.control.Label
        UpperEditFieldD                matlab.ui.control.NumericEditField
        DiffMinChangeEditField_2Label  matlab.ui.control.Label
        DiffMinChangeEditField         matlab.ui.control.NumericEditField
        DiffMaxChangeEditField_2Label  matlab.ui.control.Label
        DiffMaxChangeEditField         matlab.ui.control.NumericEditField
        StartPointEditFieldLabel_2     matlab.ui.control.Label
        StartPointEditFieldD           matlab.ui.control.NumericEditField
        RadialResolutionEditField_2Label  matlab.ui.control.Label
        RadialResolutionEditField      matlab.ui.control.NumericEditField
        RadiusLimitEditField_2Label    matlab.ui.control.Label
        RadiusLimitEditField           matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data FTIRexperiment % Description
        nmax (1,1) double
    end
    
    methods (Access = private)
        
        function output = displayData(app)
            output = ["You have "+size(app.data.data,2)+" spectra.";"Sample Volume is "+app.data.volume+" μL.";"Spacer Size is "+app.data.pathLength+" μm.";
                "Gas Factor is "+app.data.gasFactor+".";"Radius is "+string(app.data.radius)+" μm."];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadNewDataButton
        function LoadNewDataButtonPushed(app, event)
            [spectra,freq] = LoadSpectra();
            app.data = FTIRexperiment(spectra,freq(:,1),0,1,0,"");
            app.CurrentdataTextArea.Value = displayData(app);
            app.PlotSpectraButton.Enable = true;
        end

        % Button pushed function: ClearDataButton
        function ClearDataButtonPushed(app, event)
            app.data = FTIRexperiment(0,0,0,1,0,"");
            app.CurrentdataTextArea.Value = "You have no data.";
            cla(app.UIAxes)
            app.UIAxes.XLimMode = 'auto';
            app.UIAxes.YLimMode = 'auto';
            cla(app.UIAxes2)
            app.UIAxes2.YLimMode = 'auto';
            cla(app.UIAxes3)
            app.SampleVolumeLEditField.Value = 0;
            app.SpacerSizemEditField.Value = 0;
            app.TimeIntervalsEditField.Value = 0;
            app.PlotSpectraButton.Enable = false;
            app.FinalConcTextArea.Value = "";
            app.TextArea.Value = "";
            app.DiffusionCoefficientTextArea.Value = "";
            app.NmaxSpinner.Value = 0;
            app.StartPointEditFieldD.Value = 2000;
            app.LowerEditFieldD.Value = 0;
            app.UpperEditFieldD.Value = Inf;
            app.DiffMaxChangeEditField.Value = 1;
            app.DiffMinChangeEditField.Value = 0;
            app.SeeFullFitCurveCheckBox.Value = false;
            app.SeeFullFitCurveCheckBox.Enable = "off";
            app.TryFitButton.Enable = "off";
            app.SaturatedConcButtonGroup.SelectedObject = app.SelectOneButton;
            app.OtherparametersTextArea.Value = "";
        end

        % Button pushed function: PlotSpectraButton
        function PlotSpectraButtonPushed(app, event)
            cla(app.UIAxes);
            cla(app.UIAxes2);
            
            %find the indicies for the amount of spectra desired
            spectraIndicies = zeros(1,10);
            interval = ceil(size(app.data.data,2)/10);
            for ii = 0:10-1
                spectraIndicies(ii+1) = 1+(ii*interval);
            end
            if spectraIndicies(end) ~= size(app.data.data,2)
                spectraIndicies = [spectraIndicies size(app.data.data,2)];
            end
            
            for ii = spectraIndicies
                temp = app.data.fittedSpectra(ii).fobj;
                pf = co2GasLineFitFunction(app.data.fittedSpectra(ii).x,temp.center,temp.w_g,temp.w_l,...
                    temp.a1,temp.a2,0,0,0);
                plot(app.UIAxes2,app.data.fittedSpectra(ii).x,pf)
                hold on
            end
            hold off
            
            %converts data in f to vector of concentration values of CO2
            %for each spectrum, generates time axis, returns a plot of
            %concentration vs time
            %syntax: plotConcOverTime(f)
            plot(app.UIAxes,timeAxis(app.data),fittedConcOverTime(app.data),'color','red');
            app.PlotSpectraButton.Enable = false;
        end

        % Value changed function: SampleVolumeLEditField
        function SampleVolumeLEditFieldValueChanged(app, event)
            app.data.volume = app.SampleVolumeLEditField.Value;
            app.data.radius = sqrt(app.data.volume*1e9/(pi*app.data.pathLength));
            app.CurrentdataTextArea.Value = displayData(app);
            app.PlotSpectraButton.Enable = true;
        end

        % Value changed function: SpacerSizemEditField
        function SpacerSizemEditFieldValueChanged(app, event)
            app.data.pathLength = app.SpacerSizemEditField.Value;
            app.data.radius = sqrt(app.data.volume*1e9/(pi*app.data.pathLength));
            app.CurrentdataTextArea.Value = displayData(app);
            app.PlotSpectraButton.Enable = true;
        end

        % Value changed function: TimeIntervalsEditField
        function TimeIntervalsEditFieldValueChanged(app, event)
            app.data.timeInterval = app.TimeIntervalsEditField.Value;
            app.CurrentdataTextArea.Value = displayData(app);
            app.PlotSpectraButton.Enable = true;
        end

        % Callback function
        function NmaxSpinnerValueChanged(app, event)
            value = app.NmaxSpinner.Value;
            app.nmax = value;
            app.TryFitButton.Enable = "on";
            app.SeeFullFitCurveCheckBox.Enable = "off";
        end

        % Callback function
        function TryFitButtonPushed(app, event)
            PlotSpectraButtonPushed(app);
            t = timeAxis(app.data);
            sigma = 1;
            fcnCall = "diffusion(t,D,"+string(app.data.radius)+","+string(app.data.finalConc)+...
                ","+string(app.nmax)+","+string(app.RadialResolutionEditField.Value)+","+...
                string(app.RadiusLimitEditField.Value)+","+string(sigma)+")";
            type = fittype(fcnCall,'independent',{'t'},'coefficients',{'D'});
            fo = fitoptions(type);
            fo.StartPoint = app.StartPointEditFieldD.Value;
            fo.Lower = app.LowerEditFieldD.Value; 
            fo.Upper = app.UpperEditFieldD.Value;
            fo.DiffMinChange = app.DiffMinChangeEditField.Value;
            fo.DiffMaxChange = app.DiffMaxChangeEditField.Value;
            [testfit,gof] = fit(t',concOverTime(app.data)',type,fo);
            app.DiffusionCoefficientTextArea.Value = string(testfit.D);
            app.OtherparametersTextArea.Value = "R^2 = "+string(gof.rsquare);
            hold(app.UIAxes,"on")
            times = timeAxis(app.data);
            axes(app.UIAxes);
            if app.SeeFullFitCurveCheckBox.Value == false
                app.UIAxes.XLimMode = 'auto';
                app.UIAxes.YLimMode = 'auto';
                %plot(testfit);
                plot(app.UIAxes,timeAxis(app.data),diffusionModel(app.data.radius,app.data.finalConc,app.nmax,fits(1),fits(2),timeAxis(app.data)),'color','blue')
            elseif app.SeeFullFitCurveCheckBox.Value == true
                app.UIAxes.XLim = [0 4*times(end)];
                app.UIAxes.YLim = [0 app.data.finalConc];
                %plot(testfit);
                plot(app.UIAxes,0:0.01:4*times(end),diffusionModel(app.data.radius,app.data.finalConc,app.nmax,fits(1),fits(2),0:0.01:4*times(end)),'color','blue')
            end
            app.TryFitButton.Enable = "off";
            app.SeeFullFitCurveCheckBox.Enable = "on";
        end

        % Callback function
        function SaturatedConcButtonGroupSelectionChange(app, event)
            selectedButton = app.SaturatedConcButtonGroup.SelectedObject;
            if selectedButton.Text == "Use Last Spectrum"
                app.data.finalSpectrum = app.data.data(:,end);
                app.data = getFinalConc(app.data);
                app.FinalConcTextArea.Value = string(app.data.finalConc)+" M";
            elseif selectedButton.Text == "Get Final Spectrum"
                app.data.finalSpectrum = LoadSpectra();
                app.data = getFinalConc(app.data);
                app.FinalConcTextArea.Value = string(app.data.finalConc)+" M";
            end
        end

        % Callback function
        function FitParameterChanged(app, event)
            app.TryFitButton.Enable = "on";
            app.SeeFullFitCurveCheckBox.Enable = "off";
        end

        % Button pushed function: TestParametersButton
        function TestParametersButtonPushed(app, event)
            % have the user select which spectrum to guess from
            ii = app.SpectrumEditField.Value;
            
            % set the fit range
            range1 = [2290 2390];
            
            % set starting point using values from the user
            center = app.centerEditField.Value;
            wg = app.wgEditField.Value;
            wl = app.wlEditField.Value;
            a1 = app.a1EditField.Value;
            a2 = app.a2EditField.Value; % expected Boltzmann factor for bend
            a3 = app.a3EditField.Value; % gas lines
            c0 = app.c0EditField.Value; % baseline offset
            c1 = app.c1EditField.Value; % baseline slope
            
            %fit function requires fliipped inputs
            freq = flip(app.data.freqAxis);
            s = flip(app.data.data(:,ii));
            
            %get x and y data for the fit
            ind1 = find(freq>=range1(1) & freq<range1(2));
            x = freq(ind1);
            ydata = s(ind1);
            
            %plot the fitted function using user parameters
            yfit = co2GasLineFitFunction(x,center,wg,wl,a1,a2,a3,c0,c1);
            res = ydata-yfit;
            sse = sum(res.^2);
            
            plot(app.UIAxes3,x,ydata,'o',x,yfit,x,res-0.1,'r-o')
            %app.UIAxes3.Title = (sprintf('Initial guess SSE = %f',sse));
        end

        % Button pushed function: DoFitButton
        function DoFitButtonPushed(app, event)
            app.TextArea.Value = "Running...";
            T = tic; %time the fitting for later display
            app.data = gasLineFit(app.data,app.centerEditField.Value,app.wgEditField.Value,app.wlEditField.Value,...
                app.a1EditField.Value,app.a2EditField.Value,app.a3EditField.Value,app.c0EditField.Value,...
                app.c1EditField.Value);
            stop = toc(T);
            
            %selecte 4 evenly placed fits to plot
            n_spectra = size(app.data.data,2);
            iis = ceil([1 n_spectra/4 n_spectra*3/4 n_spectra]);
            cla(app.UIAxes3)
            for ii = iis
                plot(app.UIAxes3,app.data.fittedSpectra(ii).x,app.data.fittedSpectra(ii).ydata,'o',...
                    app.data.fittedSpectra(ii).x,app.data.fittedSpectra(ii).yfit,...
                    app.data.fittedSpectra(ii).x,app.data.fittedSpectra(ii).res-0.1,'ro')
                hold(app.UIAxes3,'on')
            end
            hold(app.UIAxes3,'off')
            
            %let the user know how it went
            app.TextArea.Value = "";
            tl = 0;
            for ii = 1:n_spectra
                if app.data.fittedSpectra(ii).O.exitflag < 1
                    app.TextArea.Value = [app.TextAreaValue.Value;'Spectrum '+ii+' did not converge!!! Results might not be trustworthy.'];
                    tl = tl+1;
                end
            end
            if tl==0
                app.TextArea.Value = "All fits were successful.";
            end
            app.TextArea.Value = [app.TextArea.Value;"Fitting took "+stop+" seconds."];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CurrentdataLabel = uilabel(app.UIFigure);
            app.CurrentdataLabel.Text = 'Current data:';
            app.CurrentdataTextArea = uitextarea(app.UIFigure);
            app.CurrentdataTextArea.Value = {'You have no data.'};
            app.DiffusionCoefficientTextAreaLabel = uilabel(app.UIFigure);
            app.DiffusionCoefficientTextAreaLabel.Text = 'Diffusion Coefficient';
            app.DiffusionCoefficientTextArea = uitextarea(app.UIFigure);
            app.OtherparametersTextAreaLabel = uilabel(app.UIFigure);
            app.OtherparametersTextAreaLabel.Text = 'Other parameters';
            app.OtherparametersTextArea = uitextarea(app.UIFigure);
            app.OtherparametersTextArea.Value = {''; ''};
            app.TabGroup = uitabgroup(app.UIFigure);
            app.GasLineFitTab = uitab(app.TabGroup);
            app.GasLineFitTab.Title = 'Gas Line Fit';
            app.SpectrumEditFieldLabel = uilabel(app.GasLineFitTab);
            app.SpectrumEditFieldLabel.Text = 'Spectrum #';
            app.SpectrumEditField = uieditfield(app.GasLineFitTab, 'numeric');
            app.centerEditFieldLabel = uilabel(app.GasLineFitTab);
            app.centerEditFieldLabel.Text = 'center';
            app.centerEditField = uieditfield(app.GasLineFitTab, 'numeric');
            app.centerEditField.ValueDisplayFormat = '%.1f';
            app.centerEditField.Value = 2339;
            app.wgEditFieldLabel = uilabel(app.GasLineFitTab);
            app.wgEditFieldLabel.Text = 'wg';
            app.wgEditField = uieditfield(app.GasLineFitTab, 'numeric');
            app.wgEditField.Value = 1.9;
            app.wlEditFieldLabel = uilabel(app.GasLineFitTab);
            app.wlEditFieldLabel.Text = 'wl';
            app.wlEditField = uieditfield(app.GasLineFitTab, 'numeric');
            app.wlEditField.Value = 1.7;
            app.a1EditFieldLabel = uilabel(app.GasLineFitTab);
            app.a1EditFieldLabel.Text = 'a1';
            app.a1EditField = uieditfield(app.GasLineFitTab, 'numeric');
            app.a1EditField.Value = 1;
            app.a2EditFieldLabel = uilabel(app.GasLineFitTab);
            app.a2EditFieldLabel.Text = 'a2';
            app.a2EditField = uieditfield(app.GasLineFitTab, 'numeric');
            app.a2EditField.Value = 0.07;
            app.a3EditFieldLabel = uilabel(app.GasLineFitTab);
            app.a3EditFieldLabel.Text = 'a3';
            app.a3EditField = uieditfield(app.GasLineFitTab, 'numeric');
            app.c0EditFieldLabel = uilabel(app.GasLineFitTab);
            app.c0EditFieldLabel.Text = 'c0';
            app.c0EditField = uieditfield(app.GasLineFitTab, 'numeric');
            app.c1EditFieldLabel = uilabel(app.GasLineFitTab);
            app.c1EditFieldLabel.Text = 'c1';
            app.c1EditField = uieditfield(app.GasLineFitTab, 'numeric');
            app.c1EditField.Value = 1e-06;
            app.InitalFitGuessLabel = uilabel(app.GasLineFitTab);
            app.InitalFitGuessLabel.Text = 'Inital Fit Guess';
            app.TestParametersButton = uibutton(app.GasLineFitTab, 'push');
            app.TestParametersButton.ButtonPushedFcn = createCallbackFcn(app, @TestParametersButtonPushed, true);
            app.TestParametersButton.Text = 'Test Parameters';
            app.DoFitButton = uibutton(app.GasLineFitTab, 'push');
            app.DoFitButton.ButtonPushedFcn = createCallbackFcn(app, @DoFitButtonPushed, true);
            app.DoFitButton.Text = 'Do Fit';
            app.LoadNewDataButton = uibutton(app.GasLineFitTab, 'push');
            app.LoadNewDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNewDataButtonPushed, true);
            app.LoadNewDataButton.Text = 'Load New Data';
            app.ClearDataButton = uibutton(app.GasLineFitTab, 'push');
            app.ClearDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButtonPushed, true);
            app.ClearDataButton.Text = 'Clear Data';
            app.TextArea = uitextarea(app.GasLineFitTab);
            app.UIAxes3 = uiaxes(app.GasLineFitTab);
            title(app.UIAxes3, 'FTIR Spectrum of the CO2 Peak')
            xlabel(app.UIAxes3, 'frequency (cm^{-1})')
            ylabel(app.UIAxes3, 'absorbance (AU)')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.XLim = [2290 2390];
            app.ExamineSpectraTab = uitab(app.TabGroup);
            app.ExamineSpectraTab.Title = 'Examine Spectra';
            app.PlotSpectraButton = uibutton(app.ExamineSpectraTab, 'push');
            app.PlotSpectraButton.ButtonPushedFcn = createCallbackFcn(app, @PlotSpectraButtonPushed, true);
            app.PlotSpectraButton.Enable = 'off';
            app.PlotSpectraButton.Text = 'Plot Spectra';
            app.SampleVolumeLEditField_2Label = uilabel(app.ExamineSpectraTab);
            app.SampleVolumeLEditField_2Label.Text = 'Sample Volume (μL)';
            app.SampleVolumeLEditField = uieditfield(app.ExamineSpectraTab, 'numeric');
            app.SampleVolumeLEditField.ValueChangedFcn = createCallbackFcn(app, @SampleVolumeLEditFieldValueChanged, true);
            app.SpacerSizemEditField_2Label = uilabel(app.ExamineSpectraTab);
            app.SpacerSizemEditField_2Label.Text = 'Spacer Size (μm)';
            app.SpacerSizemEditField = uieditfield(app.ExamineSpectraTab, 'numeric');
            app.SpacerSizemEditField.ValueChangedFcn = createCallbackFcn(app, @SpacerSizemEditFieldValueChanged, true);
            app.TimeIntervalsEditField_2Label = uilabel(app.ExamineSpectraTab);
            app.TimeIntervalsEditField_2Label.Text = 'Time Interval (s)';
            app.TimeIntervalsEditField = uieditfield(app.ExamineSpectraTab, 'numeric');
            app.TimeIntervalsEditField.ValueChangedFcn = createCallbackFcn(app, @TimeIntervalsEditFieldValueChanged, true);
            app.ExperimentalParametersLabel = uilabel(app.ExamineSpectraTab);
            app.ExperimentalParametersLabel.Text = 'Experimental Parameters';
            app.UIAxes2 = uiaxes(app.ExamineSpectraTab);
            title(app.UIAxes2, '*Fitted* FTIR Spectra of the CO2 Peak')
            xlabel(app.UIAxes2, 'frequency (cm^{-1})')
            ylabel(app.UIAxes2, 'absorbance (AU)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [2290 2390];
            app.CurveFittingTab = uitab(app.TabGroup);
            app.CurveFittingTab.Title = 'Curve Fitting';
            app.FinalConcTextArea = uitextarea(app.CurveFittingTab);
            app.FinalConcTextArea.Value = {''; ''};
            app.TryFitButton = uibutton(app.CurveFittingTab, 'push');
            app.TryFitButton.Enable = 'off';
            app.TryFitButton.Text = 'Try Fit';
            app.SaturatedConcButtonGroup = uibuttongroup(app.CurveFittingTab);
            app.SaturatedConcButtonGroup.Title = 'Saturated Conc';
            app.SelectOneButton = uitogglebutton(app.SaturatedConcButtonGroup);
            app.SelectOneButton.Text = 'Select One';
            app.UseLastSpectrumButton = uitogglebutton(app.SaturatedConcButtonGroup);
            app.UseLastSpectrumButton.Text = 'Use Last Spectrum';
            app.GetFinalSpectrumButton = uitogglebutton(app.SaturatedConcButtonGroup);
            app.GetFinalSpectrumButton.Text = 'Get Final Spectrum';
            app.SeeFullFitCurveCheckBox = uicheckbox(app.CurveFittingTab);
            app.SeeFullFitCurveCheckBox.Enable = 'off';
            app.SeeFullFitCurveCheckBox.Text = 'See Full Fit Curve';
            app.NmaxSpinner_2Label = uilabel(app.CurveFittingTab);
            app.NmaxSpinner_2Label.Text = 'Nmax';
            app.NmaxSpinner = uispinner(app.CurveFittingTab);
            app.NmaxSpinner.Limits = [0 1000];
            app.LowerEditFieldLabel_2 = uilabel(app.CurveFittingTab);
            app.LowerEditFieldLabel_2.Text = 'Lower';
            app.LowerEditFieldD = uieditfield(app.CurveFittingTab, 'numeric');
            app.LowerEditFieldD.Limits = [0 Inf];
            app.UpperEditFieldLabel_2 = uilabel(app.CurveFittingTab);
            app.UpperEditFieldLabel_2.Text = 'Upper';
            app.UpperEditFieldD = uieditfield(app.CurveFittingTab, 'numeric');
            app.UpperEditFieldD.Limits = [0 Inf];
            app.UpperEditFieldD.Value = Inf;
            app.DiffMinChangeEditField_2Label = uilabel(app.CurveFittingTab);
            app.DiffMinChangeEditField_2Label.Text = 'DiffMinChange';
            app.DiffMinChangeEditField = uieditfield(app.CurveFittingTab, 'numeric');
            app.DiffMaxChangeEditField_2Label = uilabel(app.CurveFittingTab);
            app.DiffMaxChangeEditField_2Label.Text = 'DiffMaxChange';
            app.DiffMaxChangeEditField = uieditfield(app.CurveFittingTab, 'numeric');
            app.DiffMaxChangeEditField.Value = 1;
            app.StartPointEditFieldLabel_2 = uilabel(app.CurveFittingTab);
            app.StartPointEditFieldLabel_2.Text = 'Start Point';
            app.StartPointEditFieldD = uieditfield(app.CurveFittingTab, 'numeric');
            app.StartPointEditFieldD.Value = 2000;
            app.RadialResolutionEditField_2Label = uilabel(app.CurveFittingTab);
            app.RadialResolutionEditField_2Label.Text = 'Radial Resolution';
            app.RadialResolutionEditField = uieditfield(app.CurveFittingTab, 'numeric');
            app.RadiusLimitEditField_2Label = uilabel(app.CurveFittingTab);
            app.RadiusLimitEditField_2Label.Text = 'Radius Limit';
            app.RadiusLimitEditField = uieditfield(app.CurveFittingTab, 'numeric');
            app.RadiusLimitEditField.Value = 1;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Concentration of CO2 vs Time')
            xlabel(app.UIAxes, 'time (s)')
            ylabel(app.UIAxes, 'concentration (M)')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FTIR_data_analysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end